classdef light_app_v_9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LightningTab                  matlab.ui.container.Tab
        L_Device                      matlab.ui.control.Lamp
        L_Files_Light                 matlab.ui.control.Lamp
        Execute_Light                 matlab.ui.control.Button
        Count_Light                   matlab.ui.control.Label
        Ex_Label_Light                matlab.ui.control.Label
        SelectmeasurementdeviceLabel  matlab.ui.control.Label
        Select_Device                 matlab.ui.control.DropDown
        Select_Button_Light           matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel  matlab.ui.control.Label
        Select_Label_Light            matlab.ui.control.EditField
        Lam                           matlab.ui.control.Lamp
        Save_average_Box              matlab.ui.control.CheckBox
        Save_N_th_Box                 matlab.ui.control.CheckBox
        N_th_field                    matlab.ui.control.NumericEditField
        PhotochromTab                 matlab.ui.container.Tab
        Execute_Photo                 matlab.ui.control.Button
        Count_Photo                   matlab.ui.control.Label
        Ex_Label_Photo                matlab.ui.control.Label
        Select_Files_Photo            matlab.ui.control.Button
        SelectingfilesforprocessingEditFieldLabel_2  matlab.ui.control.Label
        Select_Label_Photo            matlab.ui.control.EditField
        ReflectanceLabel              matlab.ui.control.Label
        Box_1nm                       matlab.ui.control.CheckBox
        Box_5nm                       matlab.ui.control.CheckBox
        Save_Label                    matlab.ui.control.Label
        Spec_Number                   matlab.ui.control.NumericEditField
        KSLabel                       matlab.ui.control.Label
        Box_Spec_KS                   matlab.ui.control.CheckBox
        Box_Spec_Range                matlab.ui.control.CheckBox
        Box_KS_1nm                    matlab.ui.control.CheckBox
        Box_KS_5nm                    matlab.ui.control.CheckBox
        Spec_Range_Top                matlab.ui.control.NumericEditField
        Just                          matlab.ui.control.Label
        Spec_Range_Bot                matlab.ui.control.NumericEditField
        Box_Save_Together             matlab.ui.control.CheckBox
        Time_Range_Bot                matlab.ui.control.NumericEditField
        Just_2                        matlab.ui.control.Label
        Time_Range_Top                matlab.ui.control.NumericEditField
        Cycle_Count                   matlab.ui.control.NumericEditField
        CyclecountLabel               matlab.ui.control.Label
        UVonoffLabel                  matlab.ui.control.Label
        DeviceDropDownLabel           matlab.ui.control.Label
        Device_Photo                  matlab.ui.control.DropDown
        Force_Stop                    matlab.ui.control.StateButton
        Time_Count_Photo              matlab.ui.control.Label
        Box_Analysis                  matlab.ui.control.CheckBox
        OnlyanalysisButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        files
        path = ' ';
        index %This is an array of numbers whch will be selected as "core" for computing chosen nanometer
        pass %Propably not useful just for checking
        rounding %Results that are outputted will be rounded to 4
        nm %Specific nanometer for saving an
        nm_range %Nanometer range for saving
        timing %Stores information about exposure, decay and cycles
        slash %Used for making filepath win=\, mac=/
    end
    
    methods (Access = private)
        
        function Check_Ocean_View(app)
            app.L_Files_Light.Visible = 'off';
            app.L_Device.Visible='off';
            if app.path == ' '
                app.L_Files_Light.Visible = 'on';
                app.pass=0;
            elseif string(app.Select_Device.Value) == "Select"
                app.L_Device.Visible='on';
                app.pass=0;
            else
                app.pass=1;
            end
            
        end
        
        function Ocean_View_Optics(app)
            
            N = length(app.files);
            excel_path=fullfile(app.path,'Processed data.xlsx');
            app.Count_Light.Text = "Importing";
            drawnow
            %Creating table for storing and outputing the results I hope there won't be
            %need for more than 1024 columns
            
            %Core processing loop
            for i = 1:N
                app.Count_Light.Text = i+"/"+N;
                drawnow
                file_path=fullfile(app.path, app.files(1,i));
                
                
                %This makes the table. The cells are being read as a character string,
                %which are then transformed into numbers while removing unnecessary cells
                A = readmatrix(file_path{1},'Delimiter','\t','DecimalSeparator',',');
                A=A(:,3:end);
                
                range = [floor(A(1,1))+2 ceil(A(1,end))-2];
                nano = range(1):range(2);
                high = numel(A)/length(A);
                result_1nm=zeros(high, length(nano));
                
                for k=range(1):range(2)
                    idx=find(min(abs(A(1,:)-k))==abs(A(1,:)-k),1); 
                    result_1nm(:,1-range(1)+k)=(A(:,idx-2)-12*A(:,idx-1)+120*A(:,idx)-12*A(:,idx+1)+A(:,idx+2))/98;
                end
                result_1nm(1,:)=nano;
                %Select every n-th nanometer
                nk=app.N_th_field.Value;
                j=0; r=1; while r~=0; j=j+1; r=rem(result_1nm(1,j),nk);  end
                result_nth_nm=result_1nm(:,j:nk:end);
                
                %Finally outputting averaged raw data from the device
                T={}; m=1;
                %if app.Save_1nm_Box.Value ; T{m}=app.files{1,1}; m=m+1; T{m}=result_1nm; m=m+1; end
                T{m}=app.files{1,1}; m=m+1; T{m}=result_1nm; m=m+1;
                if app.Save_average_Box.Value; T{m}='Average'; m=m+1; T{m}=mean(result_1nm(2:end,:)); m=m+1;
                     T{m}='Standart Deviation'; m=m+1; T{m}=std(result_1nm(2:end,:)); m=m+1; end
                if app.Save_N_th_Box.Value; T{m}="Every "+nk+"-th nanometer"; m=m+1; T{m}=result_nth_nm; m=m+1;end
                
                writematrix(T{2},excel_path,'Sheet',i,'Range','A2')
                writematrix(T{1},excel_path,'Sheet',i,'Range','A1')
                m=3;
                while m<=length(T)
                    if rem(m,2)==1; writematrix(' ',excel_path,'Sheet',i,'WriteMode','append'); end
                    writematrix(T{m},excel_path,'Sheet',i,'WriteMode','append'); m=m+1;
                end
            end
        end
        
        function Check_Photochrome(app)
            if app.path==' '
                app.pass=0;
            else
                app.pass=1;
            end
        end
        
        function Initialize(app)
            if ispc
                app.slash = "\";
            else
                app.slash = "/";
            end
            app.rounding = 4;
            app.timing.cycle = app.Cycle_Count.Value;
            app.nm = app.Spec_Number.Value;
            app.nm_range = [app.Spec_Range_Bot.Value app.Spec_Range_Top.Value];
            app.timing.exp = app.Time_Range_Top.Value;
            app.timing.dec = app.Time_Range_Bot.Value;
        end
        
        function [fit_exp,fit_dec,x] = Curve_Fitting(app,A,B,lvm)
            
            fit_exp=string(zeros([27 app.timing.cycle]));
            fit_dec=string(zeros([27 app.timing.cycle]));
            
            step=1+lvm;
            
            %Declaring fitting coefficients
            cca_a=A(app.timing.exp-10);
            cca_b=0.1;
            x=1:app.timing.cycle;
            avg=mean(B((end-ceil(app.timing.dec/4)):end-10,:));
            x(:)=(((avg(1:end-1)-A(2,:))./(A(3,:)-A(2,:))).*step+step);
            %Fitting Loop
            for i=1:app.timing.cycle
                [F,F1,F2]=fit([x(1,i);(step:step:(app.timing.exp-6)*step)'],A(1:end,i),...
                    '('+string(avg(i))+'-c)*exp(-b*x)+c','Start',[cca_b cca_a]);
                
                %Creating output table
                coefficients=[F.c-avg(i) F.b F.c log(2)/F.b 1/F.b avg(i)];
                bnds=confint(F);
                bounds=[bnds(1,1) bnds(2,1) bnds(1,2) bnds(2,2) log(2)/bnds(2,1) log(2)/bnds(1,1) 1/bnds(2,1) 1/bnds(1,1)];
                statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                
                fit_exp(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";"";"";round(bounds,app.rounding)';"Fixed";x(i);"";round(statistics,app.rounding)'],".",",");
                clear bnds bounds statistics F1 F2 coefficients
                
                if i+lvm>app.timing.cycle
                    coefficients=[0 0 0 0 0 0];
                    bounds=[0 0 0 0 0 0 0 0 0 0];
                    statistics=[0 0 0 0 0];
                else
                    
                    [F,F1,F2]=fit((0:step:(app.timing.dec-6)*step)',B(1:end,i+1),'(a-c)*exp(-b*x)+c','Start',[cca_a cca_b avg(i+1)]);
                    
                    %Creating output table
                    coefficients=[F.c-F.a F.b F.c log(2)/F.b 1/F.b F.a];
                    bnds=confint(F);
                    bounds=[bnds(1,2) bnds(2,2) bnds(1,3) bnds(2,3) log(2)/bnds(2,2) log(2)/bnds(1,2) 1/bnds(2,2) 1/bnds(1,2) bnds(1,1) bnds(2,1)];
                    statistics=[F1.dfe F1.rsquare F1.sse F1.rmse F2.numobs];
                end
                
                fit_dec(:,i)=strrep(["";"";round(coefficients,app.rounding)';"";"";"";round(bounds,app.rounding)';"";round(statistics,app.rounding)'],".",",");
                clear bnds bounds statistics F F1 F2 coefficients
            end
            clear cca_a cca_b cca_c_exp cca_c_dec
            fit_exp(1,1)="Exposure";
            fit_dec(1,1)="Decay";
        end
        
        function plotRefl(app)
            
        end
        
        function createRefl(app)
            fig1 = uifigure('Visible', 'off');
            fig1.Position = [100 100 1053 627];
            fig1.Name = 'Reflectance Plots';
            
            Reflectance_decay = uiaxes(fig1);
            title(Reflectance_decay, 'Reflectance in decay phase')
            xlabel(Reflectance_decay, 'Wavelength [nm]')
            ylabel(Reflectance_decay, 'Reflectance [%]')
            Reflectance_decay.Position = [28 309 500 300];
            
            Reflectance_exposure = uiaxes(fig1);
            title(Reflectance_exposure, 'Reflectance in exposure phase')
            xlabel(Reflectance_exposure, 'Wavelength [nm]')
            ylabel(Reflectance_exposure, 'Reflectance [%]')
            Reflectance_exposure.Position = [527 309 500 300];
            
            Reflectance_time = uiaxes(fig1);
            title(Reflectance_time, 'Reflectance in time')
            xlabel(Reflectance_time, 'Time [s]')
            ylabel(Reflectance_time, 'Reflectance [%]')
            Reflectance_time.Position = [27 10 1000 300];
            
            fig1.Visible = 'on';
        end
        
        function file_is_lvm = islvm(~,file)
            file=char(file);
            file_is_lvm=string(file(end-2:end))=="lvm";
        end
        
        function data = lvmimport(app,file)
            G=fileread(file);
            G=G(:);
            N=[175500,0,0,0;263250,351000,0,0;438750,526500,702000,0;614250,0,877500,1053000];
            N=abs(N-length(G));
            [row,col]=find(N==min(min(N)));
            switch row
                case 1
                    app.timing.dec=75;
                case 2
                    app.timing.dec=150;
                case 3
                    app.timing.dec=300;
                case 4
                    app.timing.dec=450;
            end
            switch col
                case 1
                    app.timing.exp=75;
                case 2
                    app.timing.exp=150;
                case 3
                    app.timing.exp=300;
                case 4
                    app.timing.exp=450;
            end
            data=strrep(string(ones([(app.timing.exp+app.timing.dec)*5+5,39])),'1','');k=1;i=1;l=1;tb=char(9);en=char(13);
            while i<length(G)
                if G(i)==tb
                    l=l+1;
                elseif G(i)==en
                    k=k+1;
                    i=i+1;
                    l=1;
                else
                    data(k,l)=data(k,l)+G(i);
                end
                i=i+1;
            end
            data=str2double(strrep(data,',','.'));
            data(isnan(data(:,1)),:)=[];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t=groot;
            size=app.UIFigure.Position(3:4);
            left=floor((t.ScreenSize(3)-size(1))/2);
            bottom=floor((t.ScreenSize(4)-size(2))/2);
            app.UIFigure.Position = [left bottom size(1) size(2)];
        end

        % Button pushed function: Execute_Light
        function Execute_Light_Pushed(app, event)
            app.Count_Light.Text = "Checking";
            drawnow
            Check_Ocean_View(app)
            if app.pass == 0
                app.Lam.Visible='on';
                app.Count_Light.Text = "Checking failed";
                return
            end
            %Here starts the real show
            switch string(app.Select_Device.Value)
                case 'STS'
                    Ocean_View_Optics(app);
                case 'USB 2000'
                    Ocean_View_Optics(app);
                case 'Avantes 8 Dual'
                    app.Lam.Color = [0 0 1];
                case 'Avantes 6'
                    app.Lam.Color = [1 0 1];
            end
            app.Count_Light.Text = "Finished";
        end

        % Button pushed function: Execute_Photo
        function Execute_Photo_Pushed(app, event)
            %Starting checking and processing
            app.Count_Photo.Text = "Checking";
            Initialize(app);
            Check_Photochrome(app);
            if app.pass == 0
                app.Count_Photo.Text = "Checking failed";
                return
            end
            app.Force_Stop.Visible='on';
            
            %If only one file is chosen the result is string
            if 1==ischar(app.files)
                N=1;
            else
                N = length(app.files);
            end
            
            %Index for searching and nanometer row
            if 1==1
                app.index=[3,6,9,12,14,17,20,23,25,28,31,34,37,39,42,45,48,50,53,56,59,62,64,67,70,73,76,...
                    78,81,84,87,89,92,95,98,101,103,106,109,112,115,117,120,123,126,129,132,134,137,140,143,...
                    146,148,151,154,157,160,163,165,168,171,174,177,179,182,185,188,191,194,196,199,202,205,...
                    208,211,213,216,219,222,225,228,231,233,236,239,242,245,248,251,253,256,259,262,265,268,...
                    271,273,276,279,282,285,288,291,293,296,299,302,305,308,311,314,316,319,322,325,328,331,...
                    334,337,340,342,345,348,351,354,357,360,363,366,369,371,374,377,380,383,386,389,392,395,...
                    398,401,403,406,409,412,415,418,421,424,427,430,433,436,439,441,444,447,450,453,456,459,...
                    462,465,468,471,474,477,480,483,486,489,491,494,497,500,503,506,509,512,515,518,521,524,...
                    527,530,533,536,539,542,545,548,551,554,557,560,563,566,569,572,575,578,581,584,587,590,...
                    593,596,599,602,605,608,611,614,617,620,623,626,629,632,635,638,641,644,647,650,653,656,...
                    659,662,665,668,671,674,677,680,683,686,689,692,695,698,701,704,707,711,714,717,720,723,...
                    726,729,732,735,738,741,744,747,750,753];
            end
            
            %Generating variable names
            drawnow
            v={'Measurement' strrep(strrep(strrep(app.files,' ',''),'.txt',''),'-','')};


            %I though that we need to output time row as well, maybe implement later as a feature
            
            %time_output_low=dec/2;
            %time_output_high=dec+exp/2;
            
            if app.Device_Photo.Value == "Photochrome 2"
                table_length=(app.timing.cycle * (app.timing.exp + app.timing.dec) + app.timing.dec + 20);
            elseif app.Device_Photo.Value == "Photochrome 3"
                table_length=(app.timing.cycle * (app.timing.exp + app.timing.dec) + 20);
            end
            
            %Result table for one wavelength
            together_result_ks=zeros([table_length N]);
            %together_result_w=zeros([table_length N]);
            
            
            %Core processing loop
            for i = 1:N
                if app.Force_Stop.Value==true
                    break
                end
                tic
                app.Count_Photo.Text ="Importing file "+i+app.slash+N;
                drawnow
                file_path=fullfile(app.path, app.files(1,i));
                name=strrep(app.files(1,i),'.lvm','.txt');
                
                
                %{
                This makes the table. The cells are being read as a character string,
                which are then transformed into numbers while removing unnecessary cells
                Also this is way faster than readtable function
                %}
                if islvm(app,file_path)
                    A=lvmimport(app,file_path);
                    [p,~]=size(A);
                    columns=39; step=2;
                    nano=380:10:760;


                else
                    A=readmatrix(file_path,'Delimiter',{'\t'},'DecimalSeparator',',');
                    A=A(2:end,3:end);
                    columns=401; step=1; nano=380:780;
                    [p,~]=size(A);
                    time=[[1650;2550;4350;6150],[2400;3300;5100;6900],[3900;4800;6600;8400],[5400;6300;8100;9900]];
                    k=time-p;
                    k(k<0)=k(k<0).*(-1);
                    [row,col]=find(k==min(min(k)));
                    switch row
                        case 1
                            app.timing.dec=150;
                        case 2
                            app.timing.dec=300;
                        case 3
                            app.timing.dec=600;
                        case 4
                            app.timing.dec=900;
                    end
                    switch col
                        case 1
                            app.timing.exp=150;
                        case 2
                            app.timing.exp=300;
                        case 3
                            app.timing.exp=600;
                        case 4
                            app.timing.exp=900;
                    end
                    clear k
                end
                together_result_ks=zeros([p N]);
                time_count=toc;
                app.Time_Count_Photo.Text = sprintf('%s\n%s',"Importing table "+i+app.slash+N+" took ", round(time_count,2)+" seconds");
                app.Count_Photo.Text ="Computing table "+i+app.slash+N;
                drawnow
                
                if islvm(app,file_path)
                    result=A;
                else
                    %Creating result of interpolation
                    result=zeros([p columns]);
                    
                    %Interpolation
                    for k=1:p
                        for l=1:259
                            idx=app.index(1,l);
                            result(k,l+41)=(A(k,idx-2) - 12*A(k,idx-1) + 120*A(k,idx)...
                                -12*A(k,idx+1) + A(k,idx+2))/98;
                        end
                    end
                    clear A
                    %Expanding to 380 from 421
                    for j=1:41
                        result(:,j)=result(:,42);
                    end
                    %expanding from 679 to 780
                    for j=301:401
                        result(:,j)=result(:,300);
                    end
                end
                
                %Computing K/S values
                ks_1nm=result;
                if islvm(app,file_path); div=1; else; div=100; end
                for k=1:p
                    for j=1:columns
                        B=ks_1nm(k,j)/div;
                        ks_1nm(k,j)=(((1-B)^2)/(2*B));
                    end
                end
                if islvm(app,file_path)
                    c=mean(ks_1nm(10:40,2+round(app.nm-380,-1)/10))*10<ks_1nm(:,2+round(app.nm-380,-1)/10);
                    c=abs(find(c,1)*2-[150,300,0,600,0,900]);
                    app.timing.dec=find(c==min(c))*75;
                    app.timing.exp=p/5-app.timing.dec;
                end
                
                %Selecting every 5th nanometer
                result_1nm=([nano;result]);
                ks_1nm=([nano;ks_1nm]);
                if ~islvm(app,file_path)
                    result_5nm=result_1nm(:,(1:5:end));
                    ks_5nm=ks_1nm(:,(1:5:end));
                end
                
                %Creating timetables for export
                time=[0;(0:step:p*step-1)'];
                result_1nm=([time, result_1nm]);
                ks_1nm=([time, ks_1nm]);
                if ~islvm(app,file_path)
                    result_5nm=([time, result_5nm]);
                    ks_5nm=([time, ks_5nm]);
                end
                
                %{
                subplot(2,2,1);
                title('Reflectance in minimum');
                plot(result_1nm(app.timing.dec/2,2:end))
                subplot(2,2,2);
                title('Reflectance in maximum');
                plot(result_1nm(app.timing.dec+app.timing.exp/2,2:end))
                subplot(2,2,[3,4]);
                title("Wavelength in "+app.nm+"th nanometer");
                plot(ks_1nm(2:end,app.nm-379))
                drawnow
                %}
                
                %Saving
                if 1==1
                    if app.Box_1nm.Value
                        T_result_1nm=table(round(result_1nm,app.rounding));
                        writetable(T_result_1nm,app.path+"/1 nm result "+name,'Delimiter','\t');
                        clear T_result_1nm
                    end
                    
                    if app.Box_5nm.Value && ~islvm(app,file_path)
                        T_result_5nm=table(round(result_5nm,app.rounding));
                        writetable(T_result_5nm,app.path+"/5 nm result "+name,'Delimiter','\t');
                        clear T_result_5nm
                    end
                    
                    if app.Box_KS_1nm.Value
                        T_ks_1nm=table(round(ks_1nm,app.rounding));
                        writetable(T_ks_1nm,app.path+"/KS 1nm "+name,'Delimiter','\t');
                        clear T_ks_1nm
                    end
                    
                    if app.Box_KS_5nm.Value && ~islvm(app,file_path)
                        T_ks_5nm=table(round(ks_5nm,app.rounding));
                        writetable(T_ks_5nm,app.path+"/KS 5nm "+name,'Delimiter','\t');
                        clear T_ks_5nm
                    end
                    %{
                if app.==true
                    T_nanometer=table(time,round(result_1nm(:,app.nm-378),app.rounding));
                    writetable(T_nanometer,app.path+app.slash+app.nm+" nanometer Reflectance "+name,'Delimiter','\t');
                    clear T_nanometer
                end
                    %}
                    if (app.Box_Spec_KS.Value) && (app.Box_Save_Together.Value == false)
                        if islvm(app,file_path)
                            if app.nm>760; app.nm=760;end %Fix nanometer when lvm because of rounding TODO same with range
                            T_KS_nanometer=table(time,round(ks_1nm(:,2+round(app.nm-380,-1)/10),app.rounding));
                        else
                            T_KS_nanometer=table(time,round(ks_1nm(:,app.nm-378),app.rounding));
                        end
                        writetable(T_KS_nanometer,app.path+app.slash+app.nm+" nanometer KS "+name,'Delimiter','\t');
                        clear T_KS_nanometer
                    end
                    %{
                if app.==true
                    T_nanometer_range=table(time,round(result_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                    writetable(T_nanometer_range,app.path+app.slash+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer Reflectance "+name,'Delimiter','\t');
                    clear T_nanometer_range
                end
                    %}
                    if app.Box_Spec_Range.Value
                        if islvm(app,file_path)
                            if app.nm_range(1,2)>760; app.nm_range(1,2)=760;end
                            T_KS_nanometer_range=table(time,round(ks_1nm(:,2+round(app.nm_range(1,1)-380,-1)/10:2+round(app.nm_range(1,2)-380,-1)/10),app.rounding));
                        else
                            T_KS_nanometer_range=table(time,round(ks_1nm(:,app.nm_range(1,1)-378:app.nm_range(1,2)-378),app.rounding));
                        end
                        writetable(T_KS_nanometer_range,app.path+app.slash+app.nm_range(1,1)+" : "+app.nm_range(1,2)+" nanometer KS "+name,'Delimiter','\t');
                        clear T_KS_nanometer_range
                    end
                    clear j k ks_5nm l result result_5nm time
                end
                
                %Output together n-th nanometer
                
                %together_result_w(:,i)=[result_1nm(:,app.nm-378);zeros(table_length-height(table(result_1nm)),1)];    line 365
                %together_result_ks(:,i)=[ks_1nm(:,app.nm-378);zeros(p-height(table(ks_1nm)),1)];
                % TODO need to remove later because you cant save together
                % different app.timings
                clear result_1nm
                if islvm(app,file_path)
                    if app.nm>760; app.nm=760;end
                    together_result_ks=[ks_1nm(:,2+round(app.nm-380,-1)/10);zeros(p-height(table(ks_1nm)),1)];
                else
                    together_result_ks=[ks_1nm(:,app.nm-378);zeros(p-height(table(ks_1nm)),1)];
                end
                if app.Box_Analysis.Value==true
                    app.Count_Photo.Text ="Analysis";
                    
                    table_head=["One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                        "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                        "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"];
                    warning('off','MATLAB:xlswrite:AddSheet');
                    A_exposure=zeros([app.timing.exp-5 app.timing.cycle]);
                    A_decay=zeros([app.timing.dec-5 app.timing.cycle+1]);
                    A_decay(:,1)=together_result_ks(2:app.timing.dec-4);
                    
                    [row,~]=find(together_result_ks(2:(app.timing.dec+20))>1);
                    idx=row(1)+10;
                    for j=1:app.timing.cycle
                        while 0.1<together_result_ks(idx)-together_result_ks(idx-1) || together_result_ks(idx)>1
                            idx=idx-1;
                        end
                        A_exposure(:,j)=together_result_ks(idx:idx+app.timing.exp-6);
                        idx=idx+app.timing.exp+5; if idx>length(together_result_ks)-1;break;end
                        while 0.1<together_result_ks(idx-1)-together_result_ks(idx) && together_result_ks(idx)<together_result_ks(idx-2)
                            idx=idx-1;
                        end
                        A_decay(:,j+1)=together_result_ks(idx:idx+app.timing.dec-6);
                        idx=idx+app.timing.dec+5;
                    end
                    
                    
                    clear idx exposure_mean row
                    %Exposure
                    
                    [prism_column_exposure,prism_column_decay,~]=Curve_Fitting(app,A_exposure,A_decay,islvm(app,file_path));
                    prism_column_exposure(2,1)=strrep(string(name),'.txt','');
                    prism_column_decay(2,1)=strrep(string(name),'.txt','');
                    T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                    writetable(T,app.path+app.slash+"prism.xlsx",'Sheet',i,'WriteVariableNames',false)
                    
                    clear T prism_column_decay prism_column_exposure
                    clear table_head X_analysis x together_result_ks table_head A_exposure A_decay
                end
                clear  k time_count cca_a cca_b cca_c_exp cca_c_dec
            end
            
            if (app.Box_Spec_KS.Value == true) && (app.Box_Save_Together.Value == true)
                %{
                T_concat_w=splitvars(table([(0:table_length-1)' round(together_result_w,app.rounding)]));
                T_concat_w.Properties.VariableNames = v;
                writetable(T_concat_w,app.path+app.slash+"Refl nanometer "+ app.nm,'Delimiter','\t');
                %}
                T_concat_ks=splitvars(table([(0:p-1)' round(together_result_ks,app.rounding)]));
                T_concat_ks.Properties.VariableNames = v;
                writetable(T_concat_ks,app.path+app.slash+"KS nanometer "+ app.nm,'Delimiter','\t');
                
            end
            clear i time_output_high time_output_low idx index nano nm nm_range opts nm nm_range T_concat_ks...
                T_concat_w table_length v together_result_w col row
            app.Count_Photo.Text ="Finished";
            drawnow
        end

        % Button pushed function: Select_Button_Light, 
        % Select_Files_Photo
        function Select_Pushed(app, event)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt;*.tit;*.lvm');
            if ischar(app.files); app.files={app.files}; end
            app.Select_Label_Light.Value = app.path;
            app.Select_Label_Photo.Value = app.path;
        end

        % Value changed function: Box_Spec_KS
        function Box_Spec_KS_Value_Changed(app, event)
            if app.Box_Spec_KS.Value == false
                app.Spec_Number.Enable = 'off';
                app.Box_Save_Together.Enable = 'off';
            else
                app.Spec_Number.Enable = 'on';
                app.Box_Save_Together.Enable = 'on';
            end
        end

        % Value changed function: Box_Spec_Range
        function Box_Spec_Range_Value_Changed(app, event)
            if app.Box_Spec_Range.Value == false
                app.Spec_Range_Top.Enable = 'off';
                app.Spec_Range_Bot.Enable = 'off';
                app.Just.Enable = 'off';
            else
                app.Spec_Range_Top.Enable = 'on';
                app.Spec_Range_Bot.Enable = 'on';
                app.Just.Enable = 'on';
            end
            
        end

        % Button pushed function: OnlyanalysisButton
        function Only_Analysis_Pushed(app, event)
            app.Select_Files_Photo.Enable='off';
            app.Select_Label_Photo.Enable='off';
            Initialize(app)
            [app.files,app.path]= uigetfile('MultiSelect','on','*.txt');
            if ischar(app.files); app.files={app.files}; end
            app.Count_Photo.Text ="Analysing";
            drawnow
            X_imp=readtable(app.path+app.slash+app.files);
            v=X_imp.Properties.VariableNames;
            X=table2array(X_imp(2:end,2:end));
            
            table_head=["One phase exponential fitting";"Best-fit values";"Span";"K";"Plateau";"Half Life";"Tau";"Y0";...
                "95% confidence bounds";"Span";"";"K";"";"Plateau";"";"Half life";"";"Tau";"";"Y0";"";...
                "Goodness of Fit";"Degrees of freedom";"R squared";"Sum of Squares";"Root mean squared";"Number of Y values"];
            A_exposure=zeros([app.timing.exp-5 app.timing.cycle]);
            A_decay=zeros([app.timing.dec-5 app.timing.cycle]);
            warning('off','MATLAB:xlswrite:AddSheet');
            
            [~,N]=size(X);
            for k=1:N
                [row,~]=find(X(1:(app.timing.dec+20),k)>1);
                idx=row(1)+10;
                
                for i=1:app.timing.cycle
                    while 0.1<X(idx,k)-X(idx-1,k) || X(idx,k)>1
                        idx=idx-1;
                    end
                    A_exposure(:,i)=X(idx:idx+app.timing.exp-6,k);
                    idx=idx+app.timing.exp+5;
                    while 0.01<X(idx-1,k)-X(idx,k) || X(idx,k)<X(idx-2,k)
                        idx=idx-1;
                    end
                    A_decay(:,i)=X(idx:idx+app.timing.dec-6,k);
                    idx=idx+app.timing.dec+5;
                    
                end
                clear idx exposure_mean row
                
                %Exposure
                [prism_column_exposure,prism_column_decay,x0_values]=Curve_Fitting(app,A_exposure,A_decay);
                prism_column_exposure(2,1)=v(1,k+1);
                prism_column_decay(2,1)=v(1,k+1);
                prism_column_exposure(2,1)=strrep(string(app.files(k)),'.txt','');
                prism_column_decay(2,1)=strrep(string(app.files(k)),'.txt','');
                T=splitvars(table(table_head,prism_column_exposure,cell([27 2]),prism_column_decay));
                writetable(T,app.path+app.slash+"prism.xlsx",'Sheet',k,'WriteVariableNames',false)
                clear T prism_column_decay prism_column_exposure
            end
            clear files path N X A_decay A_exposure i k table_head v X_imp x cca_a cca_b cca_c_exp cca_c_dec
            
            app.Select_Files_Photo.Enable='on';
            app.Select_Label_Photo.Enable='on';
            app.Count_Photo.Text ="Finished";
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LightningTab = uitab(app.TabGroup);
            app.LightningTab.Title = 'Lightning';
            app.L_Device = uilamp(app.LightningTab);
            app.L_Device.Visible = 'off';
            app.L_Files_Light = uilamp(app.LightningTab);
            app.L_Files_Light.Visible = 'off';
            app.Execute_Light = uibutton(app.LightningTab, 'push');
            app.Execute_Light.ButtonPushedFcn = createCallbackFcn(app, @Execute_Light_Pushed, true);
            app.Execute_Light.Text = 'Execute';
            app.Count_Light = uilabel(app.LightningTab);
            app.Count_Light.Text = '';
            app.Ex_Label_Light = uilabel(app.LightningTab);
            app.Ex_Label_Light.Text = 'Execute processing of the files';
            app.SelectmeasurementdeviceLabel = uilabel(app.LightningTab);
            app.SelectmeasurementdeviceLabel.Text = 'Select measurement device';
            app.Select_Device = uidropdown(app.LightningTab);
            app.Select_Device.Items = {'Select', 'STS', 'USB 2000', 'Avantes 8 Dual', 'Avantes 6'};
            app.Select_Device.Value = 'Select';
            app.Select_Button_Light = uibutton(app.LightningTab, 'push');
            app.Select_Button_Light.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Button_Light.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel = uilabel(app.LightningTab);
            app.SelectingfilesforprocessingEditFieldLabel.Text = 'Selecting files for processing';
            app.Select_Label_Light = uieditfield(app.LightningTab, 'text');
            app.Select_Label_Light.Editable = 'off';
            app.Select_Label_Light.Value = ' ';
            app.Lam = uilamp(app.LightningTab);
            app.Save_average_Box = uicheckbox(app.LightningTab);
            app.Save_average_Box.Text = 'Average';
            app.Save_average_Box.Value = true;
            app.Save_N_th_Box = uicheckbox(app.LightningTab);
            app.Save_N_th_Box.Text = 'Save every n-th nanometer';
            app.Save_N_th_Box.Value = true;
            app.N_th_field = uieditfield(app.LightningTab, 'numeric');
            app.N_th_field.Limits = [1 Inf];
            app.N_th_field.RoundFractionalValues = 'on';
            app.N_th_field.ValueDisplayFormat = '%.0f';
            app.N_th_field.Value = 1;
            app.PhotochromTab = uitab(app.TabGroup);
            app.PhotochromTab.Title = 'Photochrom';
            app.Execute_Photo = uibutton(app.PhotochromTab, 'push');
            app.Execute_Photo.ButtonPushedFcn = createCallbackFcn(app, @Execute_Photo_Pushed, true);
            app.Execute_Photo.Text = 'Execute';
            app.Count_Photo = uilabel(app.PhotochromTab);
            app.Count_Photo.Text = '';
            app.Ex_Label_Photo = uilabel(app.PhotochromTab);
            app.Ex_Label_Photo.Text = 'Execute processing of the files';
            app.Select_Files_Photo = uibutton(app.PhotochromTab, 'push');
            app.Select_Files_Photo.ButtonPushedFcn = createCallbackFcn(app, @Select_Pushed, true);
            app.Select_Files_Photo.Text = 'Select';
            app.SelectingfilesforprocessingEditFieldLabel_2 = uilabel(app.PhotochromTab);
            app.SelectingfilesforprocessingEditFieldLabel_2.Text = 'Selecting files for processing';
            app.Select_Label_Photo = uieditfield(app.PhotochromTab, 'text');
            app.Select_Label_Photo.Editable = 'off';
            app.Select_Label_Photo.Value = ' ';
            app.ReflectanceLabel = uilabel(app.PhotochromTab);
            app.ReflectanceLabel.Text = 'Reflectance';
            app.Box_1nm = uicheckbox(app.PhotochromTab);
            app.Box_1nm.Text = '1 nm timetable';
            app.Box_5nm = uicheckbox(app.PhotochromTab);
            app.Box_5nm.Text = '5 nm timetable';
            app.Save_Label = uilabel(app.PhotochromTab);
            app.Save_Label.Text = 'What to save';
            app.Spec_Number = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Number.Limits = [380 780];
            app.Spec_Number.RoundFractionalValues = 'on';
            app.Spec_Number.ValueDisplayFormat = '%.0f';
            app.Spec_Number.Value = 570;
            app.KSLabel = uilabel(app.PhotochromTab);
            app.KSLabel.Text = 'K/S';
            app.Box_Spec_KS = uicheckbox(app.PhotochromTab);
            app.Box_Spec_KS.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_KS_Value_Changed, true);
            app.Box_Spec_KS.Text = 'Values fo specified wavelength';
            app.Box_Spec_Range = uicheckbox(app.PhotochromTab);
            app.Box_Spec_Range.ValueChangedFcn = createCallbackFcn(app, @Box_Spec_Range_Value_Changed, true);
            app.Box_Spec_Range.Text = 'Values for specified range of wavelengths';
            app.Box_KS_1nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_1nm.Text = '1 nm timetable';
            app.Box_KS_5nm = uicheckbox(app.PhotochromTab);
            app.Box_KS_5nm.Text = '5 nm timetable';
            app.Spec_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Top.Limits = [380 780];
            app.Spec_Range_Top.RoundFractionalValues = 'on';
            app.Spec_Range_Top.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Top.Enable = 'off';
            app.Spec_Range_Top.Value = 680;
            app.Just = uilabel(app.PhotochromTab);
            app.Just.Enable = 'off';
            app.Just.Text = ':';
            app.Spec_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Spec_Range_Bot.Limits = [380 780];
            app.Spec_Range_Bot.RoundFractionalValues = 'on';
            app.Spec_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Spec_Range_Bot.Enable = 'off';
            app.Spec_Range_Bot.Value = 420;
            app.Box_Save_Together = uicheckbox(app.PhotochromTab);
            app.Box_Save_Together.Text = 'Save together';
            app.Time_Range_Bot = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Bot.LowerLimitInclusive = 'off';
            app.Time_Range_Bot.UpperLimitInclusive = 'off';
            app.Time_Range_Bot.Limits = [1 Inf];
            app.Time_Range_Bot.RoundFractionalValues = 'on';
            app.Time_Range_Bot.ValueDisplayFormat = '%.0f';
            app.Time_Range_Bot.Value = 900;
            app.Just_2 = uilabel(app.PhotochromTab);
            app.Just_2.Text = ':';
            app.Time_Range_Top = uieditfield(app.PhotochromTab, 'numeric');
            app.Time_Range_Top.LowerLimitInclusive = 'off';
            app.Time_Range_Top.UpperLimitInclusive = 'off';
            app.Time_Range_Top.Limits = [1 Inf];
            app.Time_Range_Top.RoundFractionalValues = 'on';
            app.Time_Range_Top.ValueDisplayFormat = '%.0f';
            app.Time_Range_Top.Value = 150;
            app.Cycle_Count = uieditfield(app.PhotochromTab, 'numeric');
            app.Cycle_Count.Limits = [1 Inf];
            app.Cycle_Count.Value = 5;
            app.CyclecountLabel = uilabel(app.PhotochromTab);
            app.CyclecountLabel.Text = 'Cycle count';
            app.UVonoffLabel = uilabel(app.PhotochromTab);
            app.UVonoffLabel.Text = 'UV on/off';
            app.DeviceDropDownLabel = uilabel(app.PhotochromTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.Device_Photo = uidropdown(app.PhotochromTab);
            app.Device_Photo.Items = {'Photochrome 2', 'Photochrome 3'};
            app.Device_Photo.Value = 'Photochrome 2';
            app.Force_Stop = uibutton(app.PhotochromTab, 'state');
            app.Force_Stop.Visible = 'off';
            app.Force_Stop.Text = 'Force Stop';
            app.Time_Count_Photo = uilabel(app.PhotochromTab);
            app.Time_Count_Photo.Text = '';
            app.Box_Analysis = uicheckbox(app.PhotochromTab);
            app.Box_Analysis.Text = 'Analysis';
            app.Box_Analysis.Value = true;
            app.OnlyanalysisButton = uibutton(app.PhotochromTab, 'push');
            app.OnlyanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @Only_Analysis_Pushed, true);
            app.OnlyanalysisButton.Text = 'Only analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = light_app_v_9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end